classdef Session_analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadSessionMenu                matlab.ui.container.Menu
        RunpipelineMenu                matlab.ui.container.Menu
        BatchMenu                      matlab.ui.container.Menu
        Toolbar                        matlab.ui.container.Toolbar
        DrawRoi                        matlab.ui.container.toolbar.PushTool
        ExtractTraces                  matlab.ui.container.toolbar.PushTool
        TabGroup                       matlab.ui.container.TabGroup
        SessionTab                     matlab.ui.container.Tab
        ButtonPlay                     matlab.ui.control.StateButton
        ShowDataTypeDropDown           matlab.ui.control.DropDown
        ShowDropDownLabel              matlab.ui.control.Label
        ShowStimCheckBox               matlab.ui.control.CheckBox
        ECGpeaksCheckBox               matlab.ui.control.CheckBox
        ProjectionDropDown             matlab.ui.control.DropDown
        ProjectionDropDownLabel        matlab.ui.control.Label
        ChSpinner                      matlab.ui.control.Spinner
        ChSpinnerLabel                 matlab.ui.control.Label
        Slider_time                    matlab.ui.control.Slider
        Traces                         matlab.ui.control.UIAxes
        ImgProjection                  matlab.ui.control.UIAxes
        Image                          matlab.ui.control.UIAxes
        AnalogDataTab                  matlab.ui.container.Tab
        plot_STIM                      matlab.ui.control.UIAxes
        plot_ECG                       matlab.ui.control.UIAxes
        plot_TTL                       matlab.ui.control.UIAxes
        PipelineTab                    matlab.ui.container.Tab
        AnalysisParametersPanel        matlab.ui.container.Panel
        fpsEditField                   matlab.ui.control.EditField
        fpsEditFieldLabel              matlab.ui.control.Label
        InvertCheckBox                 matlab.ui.control.CheckBox
        tau_2EditField                 matlab.ui.control.NumericEditField
        tau_2EditFieldLabel            matlab.ui.control.Label
        tau_1EditField                 matlab.ui.control.NumericEditField
        tau_1EditFieldLabel            matlab.ui.control.Label
        tau_0EditField                 matlab.ui.control.NumericEditField
        tau_0Label                     matlab.ui.control.Label
        Stim4EditField                 matlab.ui.control.EditField
        Stim4EditFieldLabel            matlab.ui.control.Label
        Stim3EditField                 matlab.ui.control.EditField
        Stim3EditFieldLabel            matlab.ui.control.Label
        Stim2EditField                 matlab.ui.control.EditField
        Stim2EditFieldLabel            matlab.ui.control.Label
        Stim1EditField                 matlab.ui.control.EditField
        Stim1EditFieldLabel            matlab.ui.control.Label
        PSTHaftersEditField            matlab.ui.control.NumericEditField
        PSTHaftersEditFieldLabel       matlab.ui.control.Label
        PSTHbeforesEditField           matlab.ui.control.NumericEditField
        PSTHbeforesEditFieldLabel      matlab.ui.control.Label
        ParametersImagingdataPanel     matlab.ui.container.Panel
        FunctionalChDropDown           matlab.ui.control.DropDown
        FunctionalChDropDownLabel      matlab.ui.control.Label
        MotionCorrectionChDropDown     matlab.ui.control.DropDown
        NumberofchannelsDropDown       matlab.ui.control.DropDown
        NumberofchannelsDropDownLabel  matlab.ui.control.Label
        MotionCorrectionChDropDownLabel  matlab.ui.control.Label
        ParametersAnalogPanel          matlab.ui.container.Panel
        ECGdistanceEditField           matlab.ui.control.NumericEditField
        ECGdistanceEditFieldLabel      matlab.ui.control.Label
        ECGthresholdEditField          matlab.ui.control.NumericEditField
        ECGthresholdEditFieldLabel     matlab.ui.control.Label
        TTLdistanceEditField           matlab.ui.control.NumericEditField
        TTLdistanceEditFieldLabel      matlab.ui.control.Label
        TTLthresholdEditField          matlab.ui.control.NumericEditField
        TTLthresholdEditFieldLabel     matlab.ui.control.Label
        TasksPanel                     matlab.ui.container.Panel
        RunButton_psth                 matlab.ui.control.Button
        RunButton_dff_calc             matlab.ui.control.Button
        RunButton_motionCorrect        matlab.ui.control.Button
        RunButton_Split                matlab.ui.control.Button
        OverrideCheckBox               matlab.ui.control.CheckBox
        ComputePSTHCheckBox            matlab.ui.control.CheckBox
        ComputedffCheckBox             matlab.ui.control.CheckBox
        MotioncorrectCheckBox          matlab.ui.control.CheckBox
        SplitchannelsCheckBox          matlab.ui.control.CheckBox
        LogTab                         matlab.ui.container.Tab
        ExecutionlogTextArea           matlab.ui.control.TextArea
        ContextMenu                    matlab.ui.container.ContextMenu
        DrawROIMenu                    matlab.ui.container.Menu
        ClearallROIsMenu               matlab.ui.container.Menu
    end


    properties (Access = public)
        PATH_TO_SESSION % pointer to session folder
        PATH_TO_DERIVATES % sessioin analysis results will go here
        SESSION_NAME % current session name
        STK % Description
        STK_MC % Motion corrected
        STK_DF % dF/f data 
        STK_MINMAX % used for keeping constant colormap display
        STK_DF_MINMAX
        PIPELINE % keep track of processes, parametes and excecution status
        ANALOG_TABLE
        STIM_TABLE % stim start/end, if STIM column present in analog data
        FRAME_LUT % frame timing from TTL data, if present
        ECG_PEAKS
        PROJ_MAX
        PROJ_AVG
        PROJ_STD
        PROJ_MED
        PROJ_DF_MAX
        PROJ_DF_AVG
        PROJ_DF_STD
        PROJ_DF_MED
        USE_GPU
        %vectore of
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %to do at startup :-)
            app.USE_GPU=0;
            if gpuDeviceCount>0
                app.USE_GPU = 1;
            end
        end

        % Menu selected function: LoadSessionMenu
        function LoadSessionMenuSelected(app, event)
            gather_pipeline_params(app)
            load_single_session(app)
        end

        % Callback function: ChSpinner, Slider_time, Slider_time
        function Slider_timeValueChanged(app, event)
            display_frame_i = round(app.Slider_time.Value);
            ch_i = app.ChSpinner.Value;
            dataType = app.ShowDataTypeDropDown.Value;
            switch dataType
                case {'Raw'}
                    imagesc(app.Image,app.STK(:,:,display_frame_i + ch_i-1),app.STK_MINMAX .* [1.05 0.95]);
                    colormap(app.Image,'Gray')
                case {'Raw (mc)'}
                case {'dFF'}
                    %dff data is only for functional channel - no need to
                    %jump between channels.
                    imagesc(app.Image,app.STK_DF(:,:,display_frame_i),app.STK_DF_MINMAX .* [1.05 0.95]);
                    colormap(app.Image,'Turbo')
            end
            colorbar(app.Image,'eastoutside')
        end

        % Callback function: DrawROIMenu, DrawRoi
        function DrawROIMenuSelected(app, event)
            add_roi(app)
        end

        % Value changed function: ProjectionDropDown
        function ProjectionDropDownValueChanged(app, event)
            update_proj_display(app)
        end

        % Callback function: ExtractTraces
        function ExtractTracesClicked(app, event)
            extract_traces(app)
        end

        % Button pushed function: RunButton_psth
        function RunButton_psthPushed(app, event)
            compute_psth(app)
        end

        % Callback function
        function RunButton_psthPushed2(app, event)
            compute
        end

        % Callback function
        function RunButton_psthPushed3(app, event)

        end

        % Button pushed function: RunButton_dff_calc
        function RunButton_dff_calcPushed(app, event)
            dff_calc(app)
        end

        % Button pushed function: RunButton_Split
        function RunButton_SplitPushed(app, event)
            split_channels(app)
        end

        % Button pushed function: RunButton_motionCorrect
        function RunButton_motionCorrectPushed(app, event)
            motion_corr(app)
        end

        % Value changed function: ShowDataTypeDropDown
        function ShowDataTypeDropDownValueChanged(app, event)
            value = app.ShowDataTypeDropDown.Value;

        end

        % Value changed function: ButtonPlay
        function ButtonPlayValueChanged(app, event)
            value = app.ButtonPlay.Value;
            limits = app.Slider_time.Limits;
            while(app.ButtonPlay.Value)
                display_frame_i = round(app.Slider_time.Value);
                next_frame = display_frame_i+1;
                if next_frame>limits(2); next_frame=limits(1); end
                app.Slider_time.Value = next_frame;
                Slider_timeValueChanged(app, event)
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadSessionMenu = uimenu(app.FileMenu);
            app.LoadSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSessionMenuSelected, true);
            app.LoadSessionMenu.Accelerator = 'o';
            app.LoadSessionMenu.Text = 'Load Session';
            app.RunpipelineMenu = uimenu(app.FileMenu);
            app.RunpipelineMenu.Accelerator = 'r';
            app.RunpipelineMenu.Text = 'Run pipeline';
            app.BatchMenu = uimenu(app.UIFigure);
            app.BatchMenu.Text = 'Batch';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.DrawRoi = uipushtool(app.Toolbar);
            app.DrawRoi.Tooltip = {'Draw ROI'};
            app.DrawRoi.ClickedCallback = createCallbackFcn(app, @DrawROIMenuSelected, true);
            app.DrawRoi.Icon = fullfile(pathToMLAPP, 'Icons', 'draw_polygon.png');
            app.ExtractTraces = uipushtool(app.Toolbar);
            app.ExtractTraces.Tooltip = {'Extract traces'};
            app.ExtractTraces.ClickedCallback = createCallbackFcn(app, @ExtractTracesClicked, true);
            app.ExtractTraces.Icon = fullfile(pathToMLAPP, 'Icons', 'brain.png');
            app.ExtractTraces.Separator = 'on';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SessionTab = uitab(app.TabGroup);
            app.SessionTab.Title = 'Session';
            app.Image = uiaxes(app.SessionTab);
            zlabel(app.Image, 'Z')
            app.ImgProjection = uiaxes(app.SessionTab);
            zlabel(app.ImgProjection, 'Z')
            app.Traces = uiaxes(app.SessionTab);
            xlabel(app.Traces, 'X')
            ylabel(app.Traces, 'Y')
            zlabel(app.Traces, 'Z')
            app.Slider_time = uislider(app.SessionTab);
            app.Slider_time.ValueChangedFcn = createCallbackFcn(app, @Slider_timeValueChanged, true);
            app.Slider_time.ValueChangingFcn = createCallbackFcn(app, @Slider_timeValueChanged, true);
            app.ChSpinnerLabel = uilabel(app.SessionTab);
            app.ChSpinnerLabel.Text = 'Ch';
            app.ChSpinner = uispinner(app.SessionTab);
            app.ChSpinner.Limits = [1 4];
            app.ChSpinner.ValueChangedFcn = createCallbackFcn(app, @Slider_timeValueChanged, true);
            app.ChSpinner.Tooltip = {'Channel to display'};
            app.ChSpinner.Value = 1;
            app.ProjectionDropDownLabel = uilabel(app.SessionTab);
            app.ProjectionDropDownLabel.Tooltip = {'Select projection to display'};
            app.ProjectionDropDownLabel.Text = 'Projection';
            app.ProjectionDropDown = uidropdown(app.SessionTab);
            app.ProjectionDropDown.Items = {'Max', 'Mean', 'Std', 'Median'};
            app.ProjectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectionDropDownValueChanged, true);
            app.ProjectionDropDown.Value = 'Max';
            app.ECGpeaksCheckBox = uicheckbox(app.SessionTab);
            app.ECGpeaksCheckBox.Text = 'ECG peaks';
            app.ECGpeaksCheckBox.Value = true;
            app.ShowStimCheckBox = uicheckbox(app.SessionTab);
            app.ShowStimCheckBox.Text = 'Show Stim';
            app.ShowStimCheckBox.Value = true;
            app.ShowDropDownLabel = uilabel(app.SessionTab);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDataTypeDropDown = uidropdown(app.SessionTab);
            app.ShowDataTypeDropDown.Items = {'Raw', 'Raw (mc)', 'dFF'};
            app.ShowDataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDataTypeDropDownValueChanged, true);
            app.ShowDataTypeDropDown.Value = 'Raw';
            app.ButtonPlay = uibutton(app.SessionTab, 'state');
            app.ButtonPlay.ValueChangedFcn = createCallbackFcn(app, @ButtonPlayValueChanged, true);
            app.ButtonPlay.Text = '>';
            app.AnalogDataTab = uitab(app.TabGroup);
            app.AnalogDataTab.Title = 'Analog Data';
            app.plot_TTL = uiaxes(app.AnalogDataTab);
            title(app.plot_TTL, 'TTL')
            xlabel(app.plot_TTL, 'Time (s)')
            ylabel(app.plot_TTL, 'V')
            zlabel(app.plot_TTL, 'Z')
            app.plot_ECG = uiaxes(app.AnalogDataTab);
            title(app.plot_ECG, 'ECG')
            xlabel(app.plot_ECG, 'Time (s)')
            ylabel(app.plot_ECG, 'V')
            zlabel(app.plot_ECG, 'Z')
            app.plot_STIM = uiaxes(app.AnalogDataTab);
            title(app.plot_STIM, 'STIM')
            xlabel(app.plot_STIM, 'Time (s)')
            ylabel(app.plot_STIM, 'V')
            zlabel(app.plot_STIM, 'Z')
            app.PipelineTab = uitab(app.TabGroup);
            app.PipelineTab.Tooltip = {'Time window before each measurement to minimize'};
            app.PipelineTab.Title = 'Pipeline';
            app.TasksPanel = uipanel(app.PipelineTab);
            app.TasksPanel.Title = 'Tasks';
            app.SplitchannelsCheckBox = uicheckbox(app.TasksPanel);
            app.SplitchannelsCheckBox.Text = 'Split channels';
            app.SplitchannelsCheckBox.Value = true;
            app.MotioncorrectCheckBox = uicheckbox(app.TasksPanel);
            app.MotioncorrectCheckBox.Text = 'Motion correct';
            app.MotioncorrectCheckBox.Value = true;
            app.ComputedffCheckBox = uicheckbox(app.TasksPanel);
            app.ComputedffCheckBox.Text = 'Compute df/f';
            app.ComputedffCheckBox.Value = true;
            app.ComputePSTHCheckBox = uicheckbox(app.TasksPanel);
            app.ComputePSTHCheckBox.Text = 'Compute PSTH';
            app.ComputePSTHCheckBox.Value = true;
            app.OverrideCheckBox = uicheckbox(app.TasksPanel);
            app.OverrideCheckBox.Text = 'Override';
            app.OverrideCheckBox.Value = true;
            app.RunButton_Split = uibutton(app.TasksPanel, 'push');
            app.RunButton_Split.ButtonPushedFcn = createCallbackFcn(app, @RunButton_SplitPushed, true);
            app.RunButton_Split.Text = 'Run';
            app.RunButton_motionCorrect = uibutton(app.TasksPanel, 'push');
            app.RunButton_motionCorrect.ButtonPushedFcn = createCallbackFcn(app, @RunButton_motionCorrectPushed, true);
            app.RunButton_motionCorrect.Text = 'Run';
            app.RunButton_dff_calc = uibutton(app.TasksPanel, 'push');
            app.RunButton_dff_calc.ButtonPushedFcn = createCallbackFcn(app, @RunButton_dff_calcPushed, true);
            app.RunButton_dff_calc.Text = 'Run';
            app.RunButton_psth = uibutton(app.TasksPanel, 'push');
            app.RunButton_psth.ButtonPushedFcn = createCallbackFcn(app, @RunButton_psthPushed, true);
            app.RunButton_psth.Text = 'Run';
            app.ParametersAnalogPanel = uipanel(app.PipelineTab);
            app.ParametersAnalogPanel.Title = 'Parameters Analog ';
            app.TTLthresholdEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.TTLthresholdEditFieldLabel.Text = 'TTL threshold';
            app.TTLthresholdEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.TTLthresholdEditField.Tooltip = {'Voltage threshold (V)'};
            app.TTLthresholdEditField.Value = 3;
            app.TTLdistanceEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.TTLdistanceEditFieldLabel.Text = 'TTL distance';
            app.TTLdistanceEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.TTLdistanceEditField.Tooltip = {'Minimal distance between TTLs (in milliseconds)'};
            app.TTLdistanceEditField.Value = 5;
            app.ECGthresholdEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.ECGthresholdEditFieldLabel.Text = 'ECG threshold';
            app.ECGthresholdEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.ECGthresholdEditField.Tooltip = {'ECG voltage threshold'};
            app.ECGthresholdEditField.Value = 0.2;
            app.ECGdistanceEditFieldLabel = uilabel(app.ParametersAnalogPanel);
            app.ECGdistanceEditFieldLabel.Tooltip = {'Minimal distance between ECG peaks (in milliseconds)'};
            app.ECGdistanceEditFieldLabel.Text = 'ECG distance';
            app.ECGdistanceEditField = uieditfield(app.ParametersAnalogPanel, 'numeric');
            app.ECGdistanceEditField.Value = 50;
            app.ParametersImagingdataPanel = uipanel(app.PipelineTab);
            app.ParametersImagingdataPanel.Title = 'Parameters Imaging data';
            app.MotionCorrectionChDropDownLabel = uilabel(app.ParametersImagingdataPanel);
            app.MotionCorrectionChDropDownLabel.Text = 'Motion Correction Ch';
            app.NumberofchannelsDropDownLabel = uilabel(app.ParametersImagingdataPanel);
            app.NumberofchannelsDropDownLabel.Text = 'Number of channels';
            app.NumberofchannelsDropDown = uidropdown(app.ParametersImagingdataPanel);
            app.NumberofchannelsDropDown.Items = {'1', '2', '3'};
            app.NumberofchannelsDropDown.Value = '2';
            app.MotionCorrectionChDropDown = uidropdown(app.ParametersImagingdataPanel);
            app.MotionCorrectionChDropDown.Items = {'1', '2', '3'};
            app.MotionCorrectionChDropDown.Value = '1';
            app.FunctionalChDropDownLabel = uilabel(app.ParametersImagingdataPanel);
            app.FunctionalChDropDownLabel.Text = 'Functional  Ch';
            app.FunctionalChDropDown = uidropdown(app.ParametersImagingdataPanel);
            app.FunctionalChDropDown.Items = {'1', '2', '3', '4'};
            app.FunctionalChDropDown.Value = '1';
            app.AnalysisParametersPanel = uipanel(app.PipelineTab);
            app.AnalysisParametersPanel.Title = 'Analysis Parameters';
            app.PSTHbeforesEditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.PSTHbeforesEditFieldLabel.Text = 'PSTH before  (s)';
            app.PSTHbeforesEditField = uieditfield(app.AnalysisParametersPanel, 'numeric');
            app.PSTHbeforesEditField.Tooltip = {'ECG voltage threshold'};
            app.PSTHbeforesEditField.Value = 1;
            app.PSTHaftersEditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.PSTHaftersEditFieldLabel.Tooltip = {'Minimal distance between ECG peaks (in milliseconds)'};
            app.PSTHaftersEditFieldLabel.Text = 'PSTH after (s)';
            app.PSTHaftersEditField = uieditfield(app.AnalysisParametersPanel, 'numeric');
            app.PSTHaftersEditField.Value = 3;
            app.Stim1EditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.Stim1EditFieldLabel.Text = 'Stim1';
            app.Stim1EditField = uieditfield(app.AnalysisParametersPanel, 'text');
            app.Stim2EditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.Stim2EditFieldLabel.Text = 'Stim2';
            app.Stim2EditField = uieditfield(app.AnalysisParametersPanel, 'text');
            app.Stim3EditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.Stim3EditFieldLabel.Text = 'Stim3';
            app.Stim3EditField = uieditfield(app.AnalysisParametersPanel, 'text');
            app.Stim4EditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.Stim4EditFieldLabel.Text = 'Stim4';
            app.Stim4EditField = uieditfield(app.AnalysisParametersPanel, 'text');
            app.tau_0Label = uilabel(app.AnalysisParametersPanel);
            app.tau_0Label.Interpreter = 'latex';
            app.tau_0Label.Text = '\tau_0';
            app.tau_0EditField = uieditfield(app.AnalysisParametersPanel, 'numeric');
            app.tau_0EditField.Tooltip = {'Exponential smoothing factor in seconds'};
            app.tau_0EditField.Value = 0.1;
            app.tau_1EditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.tau_1EditFieldLabel.Interpreter = 'latex';
            app.tau_1EditFieldLabel.Text = '\tau_1';
            app.tau_1EditField = uieditfield(app.AnalysisParametersPanel, 'numeric');
            app.tau_1EditField.Tooltip = {'F0 smoothing parameter in seconds'};
            app.tau_1EditField.Value = 0.35;
            app.tau_2EditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.tau_2EditFieldLabel.Interpreter = 'latex';
            app.tau_2EditFieldLabel.Text = '\tau_2';
            app.tau_2EditField = uieditfield(app.AnalysisParametersPanel, 'numeric');
            app.tau_2EditField.Tooltip = {'Time window before each measurement to minimize'};
            app.tau_2EditField.Value = 2;
            app.InvertCheckBox = uicheckbox(app.AnalysisParametersPanel);
            app.InvertCheckBox.Tooltip = {'False (default) if the transient is expected to be positive, True'};
            app.InvertCheckBox.Text = 'Invert';
            app.fpsEditFieldLabel = uilabel(app.AnalysisParametersPanel);
            app.fpsEditFieldLabel.Enable = 'off';
            app.fpsEditFieldLabel.Text = 'fps';
            app.fpsEditField = uieditfield(app.AnalysisParametersPanel, 'text');
            app.fpsEditField.Enable = 'off';
            app.fpsEditField.Tooltip = {'Frame rate (Hz) - computed from TTL if field is not enabled'};
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.ExecutionlogTextArea = uitextarea(app.LogTab);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DrawROIMenu = uimenu(app.ContextMenu);
            app.DrawROIMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawROIMenuSelected, true);
            app.DrawROIMenu.Text = 'Draw ROI';
            app.ClearallROIsMenu = uimenu(app.ContextMenu);
            app.ClearallROIsMenu.Text = 'Clear all ROIs';
            % Assign app.ContextMenu
            app.Image.ContextMenu = app.ContextMenu;
            app.ImgProjection.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Session_analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end